classdef Metodos_Ceros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        xsupEditField            matlab.ui.control.NumericEditField
        xsupEditFieldLabel       matlab.ui.control.Label
        xinfEditField            matlab.ui.control.NumericEditField
        xinfEditFieldLabel       matlab.ui.control.Label
        fxEditField              matlab.ui.control.EditField
        fxEditFieldLabel         matlab.ui.control.Label
        ExportDataButton         matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        CleanButton              matlab.ui.control.Button
        PlotButton               matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        NonDerivativeMethodsTab  matlab.ui.container.Tab
        x0EditField              matlab.ui.control.NumericEditField
        x0EditFieldLabel         matlab.ui.control.Label
        gxEditField              matlab.ui.control.EditField
        gxEditFieldLabel         matlab.ui.control.Label
        DeltaEditField           matlab.ui.control.NumericEditField
        DeltaEditFieldLabel      matlab.ui.control.Label
        xminEditField            matlab.ui.control.NumericEditField
        xminEditFieldLabel       matlab.ui.control.Label
        xmaxEditField            matlab.ui.control.NumericEditField
        xmaxEditFieldLabel       matlab.ui.control.Label
        FindcerosButton          matlab.ui.control.Button
        MtodoButtonGroup         matlab.ui.container.ButtonGroup
        BrentButton              matlab.ui.control.RadioButton
        SecantButton             matlab.ui.control.RadioButton
        FixedPointButton         matlab.ui.control.RadioButton
        RegulaFalsiButton        matlab.ui.control.RadioButton
        BisectionButton          matlab.ui.control.RadioButton
        DerivativeMethodsTab     matlab.ui.container.Tab
        fxEditField_3            matlab.ui.control.EditField
        fxLabel                  matlab.ui.control.Label
        DeltaEditField_2         matlab.ui.control.NumericEditField
        DeltaEditField_2Label    matlab.ui.control.Label
        x0EditField_2            matlab.ui.control.NumericEditField
        x0EditField_2Label       matlab.ui.control.Label
        fxEditField_2            matlab.ui.control.EditField
        fxEditField_2Label       matlab.ui.control.Label
        FindCerosButton2         matlab.ui.control.Button
        MethodButtonGroup2       matlab.ui.container.ButtonGroup
        HalleyButton             matlab.ui.control.RadioButton
        NewtonModifiedButton     matlab.ui.control.RadioButton
        NewtonRapsonButton       matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
        ContextMenu2             matlab.ui.container.ContextMenu
        Menu_2                   matlab.ui.container.Menu
        Menu2_2                  matlab.ui.container.Menu
        ContextMenu3             matlab.ui.container.ContextMenu
        Menu_3                   matlab.ui.container.Menu
        Menu2_3                  matlab.ui.container.Menu
    end

    methods (Access = private)
        function ChangeUITable(app, numCols, colNames)
            % Validate that the number of column names matches the number of columns
            if length(colNames) ~= numCols
                uialert(app.UIFigure, 'The number of column names does not match the number of columns.', 'Error');
                return;
            end

            % Update column names
            app.UITable.ColumnName = colNames;

            % Create a new data matrix with the correct number of columns
            app.UITable.Data = repmat({''}, 5, numCols); % 5 empty rows, numCols columns

            % Adjust column width automatically
            app.UITable.ColumnWidth = 'auto';
        end
        function [raiz, iter, iteraciones] = regulaFalsi(app, f, a, b, tol)
            max_iter = 100; % Número máximo de iteraciones
            iter = 0;
            iteraciones = []; % Matriz para almacenar valores de cada iteración
            xsup = app.xsupEditField.Value; % Límite inferior
            xinf = app.xinfEditField.Value; % Límite superior
            % Verificar cambio de signo
            if f(a) * f(b) >= 0
                error('The interval does not have a sign change.');
            end

            % Limpiar la gráfica antes de comenzar
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [xinf, xsup], 'b', 'LineWidth', 1.5); % Dibujar la función
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Regula Falsi Method');
            grid(app.UIAxes, 'on');

            % Inicialización del método
            c_old = a; % Para calcular el error inicial
            error = Inf;

            while error > tol && iter < max_iter
                % Punto de intersección de la recta secante
                c = (a * f(b) - b * f(a)) / (f(b) - f(a));
                f_c = f(c); % Evaluar función en c

                % Guardar valores de iteración
                iteraciones = [iteraciones; iter, a, b, c, f_c, abs(c - c_old)];

                % Graficar punto en la iteración
                plot(app.UIAxes, c, f_c, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6,'HandleVisibility', 'off');
                pause(0.5); % Pausa para visualizar la evolución

                % Verificar si encontramos la raíz
                if abs(f_c) < tol
                    break;
                elseif f(a) * f_c < 0
                    b = c; % La raíz está en [a, c]
                else
                    a = c; % La raíz está en [c, b]
                end

                % Actualizar valores para la siguiente iteración
                error = abs(c - c_old);
                c_old = c;
                iter = iter + 1;
            end

            raiz = c; % Aproximación final de la raíz
            hold(app.UIAxes, 'off');
        end
        function [raiz, iter, iteraciones] = biseccion(app, f, a, b, tol)
            max_iter = 100; % Número máximo de iteraciones
            iter = 0;
            iteraciones = []; % Matriz para almacenar los valores de cada iteración
            xsup = app.xsupEditField.Value; % Límite inferior
            xinf = app.xinfEditField.Value; % Límite superior
            % Verificar cambio de signo
            if f(a) * f(b) >= 0
                error('The interval does not have a sign change.');
            end

            % Limpiar la gráfica antes de comenzar
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [xinf, xsup], 'b', 'LineWidth', 1.5); % Dibujar la función
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Bisection Method');
            grid(app.UIAxes, 'on');

            while (b - a) / 2 > tol && iter < max_iter
                c = (a + b) / 2; % Punto medio
                f_c = f(c); % Evaluar función en c

                % Almacenar valores en la matriz de iteraciones
                iteraciones = [iteraciones; iter, a, b, c, f_c, (b-a)/2];

                % Graficar el punto `c` en cada iteración
                plot(app.UIAxes, c, f_c, 'ko', 'MarkerFaceColor', 'r', 'MarkerSize', 6, 'HandleVisibility', 'off');
                pause(0.5); % Pausa para visualizar el proceso paso a paso

                % Verificar si encontramos la raíz
                if abs(f_c) < tol
                    break;
                elseif f(a) * f_c < 0
                    b = c; % La raíz está en [a, c]
                else
                    a = c; % La raíz está en [c, b]
                end

                iter = iter + 1;
            end

            raiz = (a + b) / 2; % Aproximación final
            hold(app.UIAxes, 'off');
        end
        function [raiz, iter, iteraciones] = puntoFijo(app, g, x0, tol)
            max_iter = 100; % Número máximo de iteraciones
            iter = 0;
            iteraciones = []; % Matriz para almacenar valores de cada iteración
            xsup = app.xsupEditField.Value; % Límite inferior
            xinf = app.xinfEditField.Value; % Límite superior
            % Limpiar la gráfica antes de comenzar
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            % Graficar la función g(x)
            fplot(app.UIAxes, g, [xinf, xsup], 'b', 'LineWidth', 1.5);

            % Graficar la línea identidad f(x) = x
            fplot(app.UIAxes, @(x) x, [xinf, xsup], 'r--', 'LineWidth', 1.5); % Línea roja punteada

            % Etiquetas y título
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
            title(app.UIAxes, 'Fixed-Point Method');
            grid(app.UIAxes, 'on');

            % Agregar una leyenda
            legend(app.UIAxes, {'g(x)', 'y = x'}, 'Location', 'best');


            % Inicialización del método
            error = Inf;
            x_old = x0;

            while error > tol && iter < max_iter
                % Evaluar la función iterativa g(x)
                x_new = g(x_old);

                % Calcular el error
                error = abs(x_new - x_old);

                % Guardar valores de iteración
                iteraciones = [iteraciones; iter, x_old, x_new, error,0,0];

                % Graficar el punto en la iteración
                plot(app.UIAxes, x_new, g(x_new), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6, 'HandleVisibility', 'off');
                pause(0.5); % Pausa para visualizar la evolución

                % Actualizar valores para la siguiente iteración
                x_old = x_new;
                iter = iter + 1;
            end

            raiz = x_new; % Aproximación final de la raíz
            hold(app.UIAxes, 'off');
        end
        function [raiz, iter, iteraciones] = secante(app, f, x0, x1, tol)
            max_iter = 100; % Número máximo de iteraciones
            iter = 0;
            iteraciones = []; % Matriz para almacenar valores de cada iteración

            % Limpiar la gráfica antes de comenzar
            % cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [x0-2, x1+2], 'b', 'LineWidth', 1.5); % Dibujar la función f(x)
            plot(app.UIAxes, x0, f(x0), 'go', 'MarkerFaceColor', 'g', 'MarkerSize', 8); % Punto inicial
            plot(app.UIAxes, x1, f(x1), 'go', 'MarkerFaceColor', 'g', 'MarkerSize', 8); % Segundo punto inicial
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Método de la Secante');
            grid(app.UIAxes, 'on');

            % Inicialización del método
            error = Inf;

            while error > tol && iter < max_iter
                % Evitar división por cero
                if abs(f(x1) - f(x0)) < eps
                    uialert(app.UIFigure, 'Division by zero detected. Try different initial values.', 'Error');
                    return;
                end

                % Aplicar la fórmula de la secante
                x_new = x1 - (f(x1) * (x1 - x0)) / (f(x1) - f(x0));

                % Calcular el error
                error = abs(x_new - x1);

                % Guardar valores de iteración en la tabla
                iteraciones = [iteraciones; iter, x0, x1, x_new, f(x_new), error];

                % Graficar el punto en cada iteración
                plot(app.UIAxes, x_new, f(x_new), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
                pause(0.3); % Pausa para visualizar la evolución

                % Verificar si alcanzamos la convergencia
                if error < tol
                    break;
                end

                % Actualizar valores para la siguiente iteración
                x0 = x1;
                x1 = x_new;
                iter = iter + 1;
            end

            raiz = x_new; % Aproximación final de la raíz
            hold(app.UIAxes, 'off');
        end
        function [raiz, iter, iteraciones] = brent(app, f, a, b, tol)
            max_iter = 100; % Número máximo de iteraciones
            iter = 0;
            iteraciones = []; % Matriz para almacenar valores de cada iteración

            % Verificar que hay un cambio de signo en el intervalo
            fa = f(a);
            fb = f(b);
            if fa * fb >= 0
                uialert(app.UIFigure, 'The interval does not have a sign change.', 'Error');
                return;
            end

            % Limpiar la gráfica antes de comenzar
            %cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            %fplot(app.UIAxes, f, [a, b], 'b', 'LineWidth', 1.5); % Dibujar la función f(x)
            plot(app.UIAxes, a, fa, 'go', 'MarkerFaceColor', 'b', 'MarkerSize', 8); % Punto inicial a
            plot(app.UIAxes, b, fb, 'go', 'MarkerFaceColor', 'b', 'MarkerSize', 8); % Punto inicial b
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Método de Brent');
            grid(app.UIAxes, 'on');

            % Inicialización del método
            c = a;
            fc = fa;
            d = b - a;
            e = d;

            while iter < max_iter
                if fb * fc > 0
                    c = a;
                    fc = fa;
                    d = b - a;
                    e = d;
                end

                if abs(fc) < abs(fb)
                    a = b;
                    b = c;
                    c = a;
                    fa = fb;
                    fb = fc;
                    fc = fa;
                end

                tol_act = 2.0 * eps * abs(b) + 0.5 * tol;
                m = 0.5 * (c - b);

                if abs(m) <= tol_act || fb == 0
                    raiz = b;
                    break;
                end

                % Aplicar interpolación cuadrática o método de la secante
                if abs(e) >= tol_act && abs(fa) > abs(fb)
                    s = fb / fa;
                    if a == c
                        p = 2.0 * m * s;
                        q = 1.0 - s;
                    else
                        q = fa / fc;
                        r = fb / fc;
                        p = s * (2.0 * m * q * (q - r) - (b - a) * (r - 1.0));
                        q = (q - 1.0) * (r - 1.0) * (s - 1.0);
                    end
                    if p > 0, q = -q; else p = -p; end
                    if 2.0 * p < min(3.0 * m * q - abs(tol_act * q), abs(e * q))
                        e = d;
                        d = p / q;
                    else
                        d = m;
                        e = d;
                    end
                else
                    d = m;
                    e = d;
                end

                % Actualizar los valores
                a = b;
                fa = fb;
                if abs(d) > tol_act
                    b = b + d;
                else
                    b = b + sign(m) * tol_act;
                end
                fb = f(b);

                % Guardar valores de iteración
                iteraciones = [iteraciones; iter, a, b, c, fb, abs(m)];

                % Graficar el punto en cada iteración
                plot(app.UIAxes, b, fb, 'ro', 'MarkerFaceColor', 'b', 'MarkerSize', 6);
                pause(0.3); % Pausa para visualizar la evolución

                iter = iter + 1;
            end

            raiz = b; % Aproximación final de la raíz
            hold(app.UIAxes, 'off');
        end
        function ExportTableToCSV(app)
            % Obtener los datos de la UITable
            data = app.UITable.Data;  % Asegúrate de que la tabla se llame 'UITable'

            % Verificar si hay datos en la tabla
            if isempty(data)
                uialert(app.UIFigure, 'No hay datos para exportar.', 'Error');
                return;
            end

            % Pedir al usuario la ubicación y el nombre del archivo
            [file, path] = uiputfile({'*.csv'}, 'Guardar tabla como CSV');

            % Si el usuario cancela, salir de la función
            if isequal(file, 0)
                return;
            end

            % Crear la ruta completa del archivo
            filename = fullfile(path, file);

            % Guardar los datos en CSV
            try
                writetable(data, filename);
                uialert(app.UIFigure, ['Tabla exportada con éxito: ' filename], 'Éxito');
            catch
                uialert(app.UIFigure, 'Error al exportar la tabla.', 'Error');
            end
        end
        function [root, iter, iterations] = halley(app, f, df, ddf, x0, tol)
            max_iter = 100; % Maximum iterations
            iter = 0;
            iterations = []; % Store iteration values
            a = app.xsupEditField.Value; % Límite inferior
            b = app.xinfEditField.Value; % Límite superior
            % Clear the plot
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [b, a], 'b', 'LineWidth', 1.5); % Plot f(x)
            plot(app.UIAxes, x0, f(x0), 'go', 'MarkerFaceColor', 'r', 'MarkerSize', 5); % Initial point
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Halley''s Method');
            grid(app.UIAxes, 'on');

            % Initialize method
            error = Inf;
            x_old = x0;

            while error > tol && iter < max_iter
                df_x = df(x_old);
                ddf_x = ddf(x_old);

                % Prevent division by zero
                denominator = 2 * (df_x^2 - f(x_old) * ddf_x);
                if abs(denominator) < eps
                    uialert(app.UIFigure, 'Denominator is too small. Try a different initial guess.', 'Error');
                    return;
                end

                % Apply Halley's formula
                x_new = x_old - (2 * f(x_old) * df_x) / denominator;

                % Compute error
                error = abs(x_new - x_old);

                % Store iteration data
                iterations = [iterations; iter, x_old, x_new, f(x_new), df_x, error];

                % Plot iteration point
                plot(app.UIAxes, x_new, f(x_new), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6, 'HandleVisibility', 'off');
                pause(0.3); % Pause to visualize

                % Check for convergence
                if error < tol
                    break;
                end

                % Update variables
                x_old = x_new;
                iter = iter + 1;
            end

            root = x_new; % Approximate root
            hold(app.UIAxes, 'off');
        end
        function [root, iter, iterations] = newtonRaphson(app, f, df, x0, tol)
            max_iter = 100; % Número máximo de iteraciones
            iter = 0;
            iterations = []; % Matriz para almacenar valores de iteración
            a = app.xsupEditField.Value; % Límite inferior
            b = app.xinfEditField.Value; % Límite superior

            % Limpiar la gráfica antes de comenzar
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [b, a], 'b', 'LineWidth', 1.5); % Dibujar f(x)
            plot(app.UIAxes, x0, f(x0), 'go', 'MarkerFaceColor', 'r', 'MarkerSize', 5); % Punto inicial
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Newton-Raphson Method');
            grid(app.UIAxes, 'on');

            % Inicialización del método
            error = Inf;
            x_old = x0;

            while error > tol && iter < max_iter
                % Evitar división por cero
                df_x = df(x_old);
                if abs(df_x) < eps
                    uialert(app.UIFigure, 'Derivative is too close to zero. Try a different initial guess.', 'Error');
                    return;
                end

                % Aplicar la fórmula de Newton-Raphson
                x_new = x_old - f(x_old) / df_x;

                % Calcular el error
                error = abs(x_new - x_old);

                % Guardar valores de iteración
                iterations = [iterations; iter, x_old, x_new, f(x_new), df_x, error];

                % Graficar el punto en cada iteración
                plot(app.UIAxes, x_new, f(x_new), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6, 'HandleVisibility', 'off');
                pause(0.3); % Pausa para visualizar la evolución

                % Verificar convergencia
                if error < tol
                    break;
                end

                % Actualizar valores
                x_old = x_new;
                iter = iter + 1;
            end

            root = x_new; % Aproximación final
            hold(app.UIAxes, 'off');
        end
        function [root, iter, iterations] = modifiedNewton(app, f, df, ddf, x0, tol)
            max_iter = 100; % Maximum number of iterations
            iter = 0;
            iterations = []; % Store iteration values
            a = app.xsupEditField.Value; % Límite inferior
            b = app.xinfEditField.Value; % Límite superior

            % Clear plot
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [b, a], 'b', 'LineWidth', 1.5); % Plot f(x)
            plot(app.UIAxes, x0, f(x0), 'go', 'MarkerFaceColor', 'r', 'MarkerSize', 5); % Initial point
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Modified Newton Method');
            grid(app.UIAxes, 'on');

            % Initialize method
            error = Inf;
            x_old = x0;

            while error > tol && iter < max_iter
                df_x = df(x_old);
                ddf_x = ddf(x_old);

                % Prevent division by zero
                denominator = (df_x^2 - f(x_old) * ddf_x);
                if abs(denominator) < eps
                    uialert(app.UIFigure, 'Denominator is too small. Try a different initial guess.', 'Error');
                    return;
                end

                % Apply the Modified Newton formula
                x_new = x_old - (f(x_old) * df_x) / denominator;

                % Compute error
                error = abs(x_new - x_old);

                % Store iteration data
                iterations = [iterations; iter, x_old, x_new, f(x_new), df_x, error];

                % Plot iteration point
                plot(app.UIAxes, x_new, f(x_new), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6, 'HandleVisibility', 'off');
                pause(0.3);

                % Check for convergence
                if error < tol
                    break;
                end

                % Update variables
                x_old = x_new;
                iter = iter + 1;
            end

            root = x_new; % Approximate root
            hold(app.UIAxes, 'off');
        end
        function [root, iter, iterations] = householder(app, f, df, ddf, x0, tol)
            max_iter = 100; % Maximum number of iterations
            iter = 0;
            iterations = []; % Store iteration values

            % Clear plot
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            fplot(app.UIAxes, f, [x0-2, x0+2], 'b', 'LineWidth', 1.5); % Plot f(x)
            plot(app.UIAxes, x0, f(x0), 'go', 'MarkerFaceColor', 'g', 'MarkerSize', 8); % Initial point
            xlabel(app.UIAxes, 'x'); ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Householder''s Method');
            grid(app.UIAxes, 'on');

            % Initialize method
            error = Inf;
            x_old = x0;

            while error > tol && iter < max_iter
                df_x = df(x_old);
                ddf_x = ddf(x_old);

                % Prevent division by zero
                if abs(df_x) < eps
                    uialert(app.UIFigure, 'Derivative is too small. Try a different initial guess.', 'Error');
                    return;
                end

                % Apply Householder's iteration formula
                correction = 1 + (f(x_old) * ddf_x) / (2 * df_x^2);
                x_new = x_old - (f(x_old) / df_x) / correction;

                % Compute error
                error = abs(x_new - x_old);

                % Store iteration data
                iterations = [iterations; iter, x_old, x_new, f(x_new), df_x, ddf_x, error];

                % Plot iteration point
                plot(app.UIAxes, x_new, f(x_new), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 6, 'HandleVisibility', 'off');
                pause(0.3);

                % Check for convergence
                if error < tol
                    break;
                end

                % Update variables
                x_old = x_new;
                iter = iter + 1;
            end

            root = x_new; % Approximate root
            hold(app.UIAxes, 'off');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SinxButtonPushed(app, event)
            x = linspace(0, 2*pi, 100); % Valores de x
            y_sin = sin(x); % Función seno
    
            hold(app.UIAxes, 'on'); % Mantiene la gráfica actual
            plot(app.UIAxes, x, y_sin, 'b', 'LineWidth', 2); % Graficar seno en azul
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'Valor de la función');
            title(app.UIAxes, 'Gráfica de sin(x) y cos(x)');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, {'sin(x)'}, 'Location', 'best'); % Agregar leyenda

        end

        % Callback function
        function CosxButtonPushed(app, event)
            x = linspace(0, 2*pi, 100); % Valores de x
            y_cos = cos(x); % Función coseno
    
            hold(app.UIAxes, 'on'); % Mantiene la gráfica actual
            plot(app.UIAxes, x, y_cos, 'r', 'LineWidth', 2); % Graficar coseno en rojo
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'Valor de la función');
            title(app.UIAxes, 'Gráfica de sin(x) y cos(x)');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, {'cos(x)'}, 'Location', 'best'); % Agregar leyenda
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes, 'reset'); % Borra todo y reinicia propiedades
            title(app.UIAxes, ''); % Borra el título
            xlabel(app.UIAxes, ''); % Borra la etiqueta del eje X
            ylabel(app.UIAxes, ''); % Borra la etiqueta del eje Y
            grid(app.UIAxes, 'off'); % Apaga la cuadrícula
            app.fxEditField.Enable = 'on';
            app.FindcerosButton.Enable = "off";
            app.FindCerosButton2.Enable = "off";
            colNames = {'-', '-', '-', '-','-','-'};

            % Call the function to update the UITable
            ChangeUITable(app, 6, colNames);

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes); % Borra el contenido del Axes
            x = linspace(app.xinfEditField.Value, app.xsupEditField.Value, 100); % Rango de valores
            func_str = app.fxEditField.Value; % Obtener la función ingresada por el usuario

            try
                y_user = eval(vectorize(func_str)); % Evaluar la función
                hold(app.UIAxes, 'on'); % Mantener las gráficas anteriores
                plot(app.UIAxes, x, y_user, 'g', 'LineWidth', 2); % Graficar en verde
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'Valor de la función');
                title(app.UIAxes, 'Ceros de una función por el método de la bisección');
                grid(app.UIAxes, 'on');

                % Actualizar la leyenda dinámicamente
                legend_entries = findobj(app.UIAxes, 'Type', 'Line'); % Encontrar líneas existentes
                legend_labels = arrayfun(@(h) get(h, 'DisplayName'), legend_entries, 'UniformOutput', false);
                legend_labels = [legend_labels, {func_str}]; % Agregar la nueva función
                legend(app.UIAxes, legend_labels, 'Location', 'best');

                % Deshabilitar el cuadro de texto después de ingresar la función
                app.fxEditField.Enable = 'off';

                app.FindcerosButton.Enable = "on"
                app.FindCerosButton2.Enable = "on"

            catch
                uialert(app.UIFigure, 'Error en la función ingresada.', 'Error');
            end


        end

        % Button pushed function: FindcerosButton
        function FindcerosButtonPushed(app, event)
            func_str = app.fxEditField.Value; % Obtener la función ingresada
            g_str = app.gxEditField.Value;
            a = app.xmaxEditField.Value; % Límite inferior
            b = app.xminEditField.Value; % Límite superior
            x_0 = app.x0EditField.Value;
            tol = app.DeltaEditField.Value; % Tolerancia de error

            try
                g = @(x) eval(vectorize(g_str)); % Función iterativa
            catch
                uialert(app.UIFigure, 'Error en la funcion de prueba','Error')
            end

            try
                % Convertir la función ingresada en función anónima
                f = @(x) eval(vectorize(func_str));
                % Verificar si los valores iniciales son válidos
                if f(a) * f(b) >= 0
                    uialert(app.UIFigure, 'The interval is not valid. f(a) and f(b) must have opposite signs.', 'Error');
                    return;
                end

                selectedMethod = app.MtodoButtonGroup.SelectedObject.Text;

                switch selectedMethod
                    case 'Bisection'
                        [raiz, iter, iteraciones] = biseccion(app, f, a, b, tol);
                        numCols = 6;
                        colNames = {'iter', 'a', 'b', 'Raiz', 'f(x)', '(b-a)/2'};
                    case 'Regula Falsi'
                        [raiz, iter, iteraciones] = regulaFalsi(app, f, a, b, tol);
                        numCols = 6;
                        colNames = { 'iter', 'a', 'b', 'c', 'f(x)', 'abs(c - c_old)'};
                    case 'Fixed Point'
                        [raiz, iter, iteraciones] = puntoFijo(app, g, x_0, tol);
                        numCols = 6;
                        colNames = {'iter', 'x_old', 'x_new', 'error','',''};
                    case 'Secant'
                        [raiz, iter, iteraciones] = secante(app, f, a, b, tol);
                        numCols = 6;
                        colNames = {'iter', 'x0', 'x1', 'x_new', 'f(x_new)', 'error'};

                    case 'Brent'
                        [raiz, iter, iteraciones] = brent(app, f, a, b, tol);
                        numCols = 6;
                        colNames = {'iter', 'a', 'b', 'c', 'fb', 'abs(m)'};


                end
                ChangeUITable(app, numCols, colNames);

                % Mostrar resultado
                mensaje = sprintf('Root found: x = %.6f in %d iterations', raiz, iter);
                uialert(app.UIFigure, mensaje, 'Result');

                % Actualizar la tabla en App Designer con los datos de iteraciones
                app.UITable.Data = array2table(iteraciones, ...
                    'VariableNames', {'Iteración', 'a', 'b', 'c', 'f(c)', 'Error'});

                % Graficar la función y el cero encontrado
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, raiz, 0, 'ko', 'MarkerFaceColor', 'r', 'MarkerSize', 8); % Raíz en verde
                legend(app.UIAxes, {func_str, 'Root Found'}, 'Location', 'best');
                hold(app.UIAxes, 'off');

            catch
                uialert(app.UIFigure, 'Error evaluating the function.', 'Error');
            end


        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            ExportTableToCSV(app);
        end

        % Selection changed function: MtodoButtonGroup
        function MtodoButtonGroupSelectionChanged(app, event)
            selectedMethod = app.MtodoButtonGroup.SelectedObject.Text;

            switch selectedMethod
                case 'Bisection'
                    app.x0EditField.Enable = "off"
                    app.gxEditField.Enable = 'off'
                    app.xmaxEditField.Enable = "on"
                    app.xminEditField.Enable = "on"
                case 'Regula Falsi'
                    app.x0EditField.Enable = "off"
                    app.gxEditField.Enable = 'off'
                    app.xmaxEditField.Enable = "on"
                    app.xminEditField.Enable = "on"
                case 'Fixed Point'
                    app.x0EditField.Enable = "on"
                    app.gxEditField.Enable = 'on'
                    app.xmaxEditField.Enable = "off"
                    app.xminEditField.Enable = "off"
                case 'Secant'
                    app.x0EditField.Enable = "off"
                    app.gxEditField.Enable = 'off'

                    app.xmaxEditField.Enable = "on"
                    app.xminEditField.Enable = "on"
                case 'Brent'
                    app.x0EditField.Enable = "off"
                    app.gxEditField.Enable = 'off'
                    app.xmaxEditField.Enable = "on"
                    app.xminEditField.Enable = "on"
            end

        end

        % Button pushed function: FindCerosButton2
        function FindCerosButton2Pushed(app, event)
            func_str = app.fxEditField.Value; % Obtener la función ingresada
            f_der = app.fxEditField_2.Value;
            f_2der = app.fxEditField_3.Value;
            x_0 = app.x0EditField_2.Value;
            tol = app.DeltaEditField_2.Value;
            a = app.xmaxEditField.Value;
            b = app.xminEditField.Value;
            try
                f_d = @(x) eval(vectorize(f_der)); % Función iterativa
                f_2d = @(x) eval(vectorize(f_2der)); % Función iterativa
            catch
                uialert(app.UIFigure, 'Error en la funcion de prueba','Error')
            end

            try
                % Convertir la función ingresada en función anónima
                f = @(x) eval(vectorize(func_str));
                % Verificar si los valores iniciales son válidos
                % if f(a) * f(b) >= 0
                %     uialert(app.UIFigure, 'El intervalo no es válido. f(a) y f(b) deben tener signos opuestos.', 'Error');
                %     return;
                % end

                selectedMethod = app.MethodButtonGroup2.SelectedObject.Text;

                switch selectedMethod
                    case 'Newton - Rapson'
                        [raiz, iter, iteraciones] = newtonRaphson(app, f, f_d, x_0, tol);
                        numCols = 6;
                        colNames = {'iter', 'x_old', 'x_new', 'f(x_new)', 'df_x', 'error'};
                        ChangeUITable(app, numCols, colNames);
                    case 'Newton-Modified'
                        [raiz, iter, iteraciones] = modifiedNewton(app, f, f_d, f_2d, x_0, tol);
                        numCols = 6;
                        colNames = {'iter', 'x_old', 'x_new', 'f(x_new)', 'df_x', 'error'};
                        ChangeUITable(app, numCols, colNames);
                    case 'Halley'
                        [raiz, iter, iteraciones] = halley(app, f, f_d, f_2d, x_0, tol);
                        numCols = 6;
                        colNames = {'iter', 'x_old', 'x_new', 'f(x_new)', 'df_x', 'error'};
                        ChangeUITable(app, numCols, colNames);
                end

                % Mostrar resultado
                mensaje = sprintf('Root found: x = %.6f en %d iteraciones', raiz, iter);
                uialert(app.UIFigure, mensaje, 'Result');

                % Actualizar la tabla en App Designer con los datos de iteraciones
                app.UITable.Data = array2table(iteraciones, ...
                    'VariableNames', {'Iteración', 'a', 'b', 'c', 'f(c)', 'Error'});

                % Graficar la función y el cero encontrado
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, raiz, 0, 'ko', 'MarkerFaceColor', 'r', 'MarkerSize', 8); % Raíz en verde
                legend(app.UIAxes, {func_str, 'Root'}, 'Location', 'best');
                hold(app.UIAxes, 'off');

            catch
                uialert(app.UIFigure, 'Error evaluating the function.', 'Error');
            end


        end

        % Value changed function: fxEditField
        function fxEditFieldValueChanged(app, event)

            try
                value = app.fxEditField.Value; % Obtener la función ingresada como cadena

                % Importar SymPy en MATLAB
                py.importlib.import_module('sympy');

                % Definir la variable simbólica en SymPy
                x = py.sympy.Symbol('x');

                try
                    % Convertir la cadena en una expresión simbólica de SymPy
                    f = py.sympy.sympify(value);

                    % Calcular derivadas
                    df = py.sympy.diff(f, x);
                    d2f = py.sympy.diff(df, x);

                    % Convertir la derivada a cadena, reemplazar "**" por "^" y mostrar en los campos de texto
                    app.fxEditField_2.Value = strrep(char(df), '**', '^');
                    app.fxEditField_3.Value = strrep(char(d2f), '**', '^');

                catch
                    uialert(app.UIFigure, 'Error computing derivative. Check function format.', 'Error');
                end

            catch
                % Mostrar un mensaje de error si la función ingresada es inválida
                uialert(app.UIFigure, 'Error computing derivative. Check function format.', 'Error');
            end


        end

        % Value changed function: fxEditField_2
        function fxEditField_2ValueChanged(app, event)
            value = app.fxEditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NonDerivativeMethodsTab = uitab(app.TabGroup);
            app.NonDerivativeMethodsTab.Title = 'Non-Derivative Methods';
            app.MtodoButtonGroup = uibuttongroup(app.NonDerivativeMethodsTab);
            app.MtodoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MtodoButtonGroupSelectionChanged, true);
            app.MtodoButtonGroup.Title = 'Método';
            app.BisectionButton = uiradiobutton(app.MtodoButtonGroup);
            app.BisectionButton.Text = 'Bisection';
            app.BisectionButton.Value = true;
            app.RegulaFalsiButton = uiradiobutton(app.MtodoButtonGroup);
            app.RegulaFalsiButton.Text = 'Regula Falsi';
            app.FixedPointButton = uiradiobutton(app.MtodoButtonGroup);
            app.FixedPointButton.Text = 'Fixed Point';
            app.SecantButton = uiradiobutton(app.MtodoButtonGroup);
            app.SecantButton.Text = 'Secant';
            app.BrentButton = uiradiobutton(app.MtodoButtonGroup);
            app.BrentButton.Text = 'Brent';
            app.FindcerosButton = uibutton(app.NonDerivativeMethodsTab, 'push');
            app.FindcerosButton.ButtonPushedFcn = createCallbackFcn(app, @FindcerosButtonPushed, true);
            app.FindcerosButton.Enable = 'off';
            app.FindcerosButton.Text = 'Find ceros';
            app.xmaxEditFieldLabel = uilabel(app.NonDerivativeMethodsTab);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.NonDerivativeMethodsTab, 'numeric');
            app.xmaxEditField.Value = -1;
            app.xminEditFieldLabel = uilabel(app.NonDerivativeMethodsTab);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.NonDerivativeMethodsTab, 'numeric');
            app.xminEditField.Value = 1;
            app.DeltaEditFieldLabel = uilabel(app.NonDerivativeMethodsTab);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.NonDerivativeMethodsTab, 'numeric');
            app.DeltaEditField.Value = 1e-06;
            app.gxEditFieldLabel = uilabel(app.NonDerivativeMethodsTab);
            app.gxEditFieldLabel.Enable = 'off';
            app.gxEditFieldLabel.Text = 'g(x)';
            app.gxEditField = uieditfield(app.NonDerivativeMethodsTab, 'text');
            app.gxEditField.Enable = 'off';
            app.gxEditField.Value = 'x';
            app.x0EditFieldLabel = uilabel(app.NonDerivativeMethodsTab);
            app.x0EditFieldLabel.Enable = 'off';
            app.x0EditFieldLabel.Text = 'x(0)';
            app.x0EditField = uieditfield(app.NonDerivativeMethodsTab, 'numeric');
            app.x0EditField.Enable = 'off';
            app.DerivativeMethodsTab = uitab(app.TabGroup);
            app.DerivativeMethodsTab.Title = 'Derivative Methods';
            app.MethodButtonGroup2 = uibuttongroup(app.DerivativeMethodsTab);
            app.MethodButtonGroup2.Title = 'Method';
            app.NewtonRapsonButton = uiradiobutton(app.MethodButtonGroup2);
            app.NewtonRapsonButton.Text = 'Newton - Rapson';
            app.NewtonRapsonButton.Value = true;
            app.NewtonModifiedButton = uiradiobutton(app.MethodButtonGroup2);
            app.NewtonModifiedButton.Text = 'Newton-Modified';
            app.HalleyButton = uiradiobutton(app.MethodButtonGroup2);
            app.HalleyButton.Text = 'Halley';
            app.FindCerosButton2 = uibutton(app.DerivativeMethodsTab, 'push');
            app.FindCerosButton2.ButtonPushedFcn = createCallbackFcn(app, @FindCerosButton2Pushed, true);
            app.FindCerosButton2.Enable = 'off';
            app.FindCerosButton2.Text = 'Find Ceros';
            app.fxEditField_2Label = uilabel(app.DerivativeMethodsTab);
            app.fxEditField_2Label.Text = 'f''(x)';
            app.fxEditField_2 = uieditfield(app.DerivativeMethodsTab, 'text');
            app.fxEditField_2.ValueChangedFcn = createCallbackFcn(app, @fxEditField_2ValueChanged, true);
            app.fxEditField_2.Value = 'cos(x)';
            app.x0EditField_2Label = uilabel(app.DerivativeMethodsTab);
            app.x0EditField_2Label.Text = 'x0';
            app.x0EditField_2 = uieditfield(app.DerivativeMethodsTab, 'numeric');
            app.x0EditField_2.Value = 1;
            app.DeltaEditField_2Label = uilabel(app.DerivativeMethodsTab);
            app.DeltaEditField_2Label.Text = 'Delta';
            app.DeltaEditField_2 = uieditfield(app.DerivativeMethodsTab, 'numeric');
            app.DeltaEditField_2.Value = 1e-06;
            app.fxLabel = uilabel(app.DerivativeMethodsTab);
            app.fxLabel.Text = 'f''''(x)';
            app.fxEditField_3 = uieditfield(app.DerivativeMethodsTab, 'text');
            app.fxEditField_3.Value = '-sin(x)';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CleanButton = uibutton(app.GridLayout, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'-'; '-'; '-'; '-'; '-'; '-'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.GridLayout, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.fxEditFieldLabel = uilabel(app.GridLayout);
            app.fxEditFieldLabel.Text = 'f(x) =';
            app.fxEditField = uieditfield(app.GridLayout, 'text');
            app.fxEditField.ValueChangedFcn = createCallbackFcn(app, @fxEditFieldValueChanged, true);
            app.fxEditField.Value = 'sin(x)';
            app.xinfEditFieldLabel = uilabel(app.GridLayout);
            app.xinfEditFieldLabel.Text = 'x inf';
            app.xinfEditField = uieditfield(app.GridLayout, 'numeric');
            app.xinfEditField.Value = -10;
            app.xsupEditFieldLabel = uilabel(app.GridLayout);
            app.xsupEditFieldLabel.Text = 'x sup';
            app.xsupEditField = uieditfield(app.GridLayout, 'numeric');
            app.xsupEditField.Value = 10;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu3);
            app.Menu2_3.Text = 'Menu2';
            % Assign app.ContextMenu3
            app.FixedPointButton.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metodos_Ceros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end